classdef actuator_console < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ActuatorConsoleUIFigure        matlab.ui.Figure
        ConfigurationMenu              matlab.ui.container.Menu
        SettingsMenu                   matlab.ui.container.Menu
        ToolsMenu                      matlab.ui.container.Menu
        DataReplayMenu                 matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        AboutMenu                      matlab.ui.container.Menu
        FBGAutoset                     matlab.ui.control.Button
        ReconnectButton                matlab.ui.control.Button
        ValveControllerComStatusLabel  matlab.ui.control.Label
        FBGComStatusLabel              matlab.ui.control.Label
        DriverClockLabel               matlab.ui.control.Label
        RobotControllerComStatusLabel  matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        ActuatorTab                    matlab.ui.container.Tab
        AnalogDCheckBox                matlab.ui.control.CheckBox
        AnalogCCheckBox                matlab.ui.control.CheckBox
        AnalogBCheckBox                matlab.ui.control.CheckBox
        AnalogACheckBox                matlab.ui.control.CheckBox
        LaunchPadPanel                 matlab.ui.container.Panel
        TuneButton                     matlab.ui.control.StateButton
        KdEditField                    matlab.ui.control.NumericEditField
        KdEditFieldLabel               matlab.ui.control.Label
        KiEditField                    matlab.ui.control.NumericEditField
        KiEditFieldLabel               matlab.ui.control.Label
        KpEditField                    matlab.ui.control.NumericEditField
        KpEditFieldLabel               matlab.ui.control.Label
        Cmd4EditField                  matlab.ui.control.NumericEditField
        Command4Label                  matlab.ui.control.Label
        Cmd3EditField                  matlab.ui.control.NumericEditField
        Command3Label                  matlab.ui.control.Label
        OperationModeDropDown          matlab.ui.control.DropDown
        ModeDropDownLabel              matlab.ui.control.Label
        Cmd2EditField                  matlab.ui.control.NumericEditField
        Command2EditFieldLabel         matlab.ui.control.Label
        Cmd1EditField                  matlab.ui.control.NumericEditField
        Command1EditFieldLabel         matlab.ui.control.Label
        CmdSendButton                  matlab.ui.control.StateButton
        ValveControllerLamp            matlab.ui.control.Lamp
        ValveControllerLampLabel       matlab.ui.control.Label
        LoggingControlButtonGroup      matlab.ui.container.ButtonGroup
        BrowseButton                   matlab.ui.control.Button
        DirectoryEditField             matlab.ui.control.EditField
        DirectoryEditFieldLabel        matlab.ui.control.Label
        TestnameEditField              matlab.ui.control.EditField
        TestnameEditFieldLabel         matlab.ui.control.Label
        AutomaticButton                matlab.ui.control.RadioButton
        SignleShotButton               matlab.ui.control.RadioButton
        EnableSwitch                   matlab.ui.control.Switch
        EnableSwitchLabel              matlab.ui.control.Label
        RobotControllerLamp            matlab.ui.control.Lamp
        RobotControllerLampLabel       matlab.ui.control.Label
        Switch                         matlab.ui.control.Switch
        SwitchLabel                    matlab.ui.control.Label
        RunButton                      matlab.ui.control.StateButton
        PlotMotorBPosCheckBox          matlab.ui.control.CheckBox
        PlotMotorAPosCheckBox          matlab.ui.control.CheckBox
        CurrentTemperatureUIAxes       matlab.ui.control.UIAxes
        MotorPositionUIAxes            matlab.ui.control.UIAxes
        FBGTab                         matlab.ui.container.Tab
        FBGCounts                      matlab.ui.control.EditField
        NumberofGratingsLabel          matlab.ui.control.Label
        ShowFBGAppWindowButton         matlab.ui.control.StateButton
        WavelengthUIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Child applications
        SettingApp
        
        % Configuration files
        ConfigFileName
        Config
        
        % Communication objects
        RobotControllerComObj
        ValveControllerComObj
        FBGComObj
        FBGWebCmdPrefix
        RXCount = 0
        
        IsControllerReady = false
        IsFBGReady = false
        
        QueryTimer
        UIUpdateTimer
        SetupFBGAppTimer
        
        % Plots and UI
        UIBufferLen
        UIAxesxTicks
        UIAxesxTicksFull
        PositionPlot1
        PositionPlot2
        CurrentPlot1
        CurrentPlot2
        CurrentPlot3
        CurrentPlot4
        WavelengthPlot1
        WavelengthPlot2
        WavelengthPlot3
        WavelengthPlot4
        
        % Status bar strings
        RobotControllerComStatusStr
        ControllerComLoadStr
        ValveControllerComStatusStr
        FBGAppStatusStr
        FBGComStatusStr
        FBGComLoadStr
        
        % UI buffer
        ControllerQueriedData
        ValveControllerQueriedData
        FBGQueriedData
        
        CmdEchoBuffer = []
        UploadedStatus = []
        DriverTimeBuffer = []
        SensorDataBuffer = []
        ValveCmdBuffer = []
        ValveUploadedStatus = []
        ValveControllerTimeBuffer = []
        
        FBGDataBuffer = []
        EncoderAPlotData
        EncoderBPlotData
        SpeedAPlotData
        SpeedBPlotData
        CurrentAPlotData
        CurrentBPlotData
        CurrentCPlotData
        CurrentDPlotData
        WavelengthAPlotData
        WavelengthBPlotData
        WavelengthCPlotData
        WavelengthDPlotData
        
        DataFileID
        DataFormatStr
        
        % State transition codes
        CmdOff2On           = 10
        CmdOn2Off           = 11
        CmdOn2Enable        = 12
        CmdEnable2On        = 13
%         CmdIdleMode2PosMode = 14
%         CmdPosMode2IdleMode = 15
        CmdTurnOff          = 0
        
    end
    
    methods (Access = public)
        
        function UpdateConfig(app, config)
            app.Config = config;
            app.ComSetup();
            app.FBGWebCmdPrefix = [ ...
                'http://', ...
                app.Config.FBGRemoteAddress, ':', ...
                num2str(app.Config.FBGControlPort), '/ILLWS' ...
            ];
            
            % Timers
            stop(app.QueryTimer);
            stop(app.UIUpdateTimer);
            app.QueryTimer.Period    = app.Config.QueryInterval;
            app.UIUpdateTimer.Period = app.Config.UIUpdateInterval;
            
            % Update axes limit
            app.MotorPositionUIAxes.XLim   = [0 app.Config.UIAxisXLim];
            app.CurrentTemperatureUIAxes.XLim = [0 app.Config.UIAxisXLim];
            app.WavelengthUIAxes.XLim      = [0 app.Config.UIAxisXLim];
            
            % Reset UI variables
            app.UIBufferLen      = app.Config.UIAxisXLim / app.Config.ControllerStepSize;
            % app.FBGBufferLen     = app.Config.UIAxisXLim * app.Config.FBGSCanRate;
            app.ResetPlotData();
            app.UIAxesxTicksFull = linspace(0, app.Config.UIAxisXLim, app.UIBufferLen);
            % app.FBGAxesxTicksFull = linspace(0, app.Config.UIAxisXLim, app.FBGBufferLen);
            
            app.DataFormatStr = strcat(repmat('%.4f\t', 1, app.Config.ControllerFrameSize - 1), '%.4f\n');
        end
        
    end
    
    methods (Access = private)
        
        function ComSetup(app)
            % Connection attempt to robot controller
            delete(app.RobotControllerComObj);
            if convertCharsToStrings(app.Config.RobotControllerProtocol) == "Serial"
                try
                    app.RobotControllerComObj = serialport( ...
                        app.Config.RobotControllerCOM, ...
                        app.Config.RobotControllerBaudRate, ...
                        "Timeout", 0.4 ...
                    );
                    app.RobotControllerComStatusStr = strcat( ...
                        'Robot Controller -> ', ...
                        app.Config.RobotControllerProtocol, ', ', ...
                        app.Config.RobotControllerCOM, ...
                        ', baud rate: ', ...
                        num2str(app.Config.RobotControllerBaudRate) ...
                    );
                    app.RobotControllerComStatusLabel.Text = app.RobotControllerComStatusStr;
                    app.IsControllerReady = true;
                catch
                    app.RobotControllerComStatusLabel.Text = "Connection with controller failed";
                end
            else
                try
                    app.RobotControllerComObj = tcpclient( ...
                        app.Config.RobotControllerRemoteAddress, ...
                        app.Config.RobotControllerPort, ...
                        'Timeout', 2 ...
                    );
                    app.RobotControllerComStatusStr = strcat( ...
                        'Robot Controller -> ', ...
                        app.Config.RobotControllerProtocol, ...
                        ', remote: ', ...
                        app.Config.RobotControllerRemoteAddress, ...
                        ', port: ', ...
                        num2str(app.Config.RobotControllerPort) ...
                    );
                    app.RobotControllerComStatusLabel.Text = app.RobotControllerComStatusStr;
                    app.IsControllerReady = true;
                catch
                    app.RobotControllerComStatusLabel.Text = "Connection with controller failed";
                end
            end
            
            % Check if valve controller is online
            delete(app.ValveControllerComObj);
            if convertCharsToStrings(app.Config.ValveControllerProtocol) == "Serial"
                try
                    app.ValveControllerComObj = serialport( ...
                        app.Config.ValveControllerCOM, ...
                        app.Config.ValveControllerBaudRate, ...
                        "Timeout", 0.4 ...
                    );
                    app.ValveControllerComStatusStr = strcat( ...
                        'Valve Controller -> ', ...
                        app.Config.ValveControllerProtocol, ', ', ...
                        app.Config.ValveControllerCOM, ...
                        ', baud rate: ', ...
                        num2str(app.Config.ValveControllerBaudRate) ...
                    );
                    app.ValveControllerComStatusLabel.Text = app.ValveControllerComStatusStr;
%                     app.IsControllerReady = true;
                catch
                    app.ValveControllerComStatusLabel.Text = "Connection with controller failed";
                end
            else
                try
                    if ~system(['ping ', app.Config.ValveControllerRemoteAddress, ' -n 2'])
                        app.ValveControllerComStatusStr = strcat( ...
                        'Valve Controller -> ', ...
                        app.Config.ValveControllerProtocol, ...
                        ', remote: ', ...
                        app.Config.ValveControllerRemoteAddress, ...
                        ', port: ', ...
                        num2str(app.Config.ValveControllerPort) ...
                    );
                    app.ValveControllerComStatusLabel.Text = app.ValveControllerComStatusStr;
                    clc;
    %                     app.IsControllerReady = true;
                    else
                        app.ValveControllerComStatusLabel.Text = "Connection with controller failed";
                    end
                catch
                    app.ValveControllerComStatusLabel.Text = "Connection with controller failed";
                end
%                 try
%                     app.ValveControllerComObj = tcpclient( ...
%                         app.Config.ValveControllerRemoteAddress, ...
%                         app.Config.ValveControllerPort, ...
%                         'Timeout', 2 ...
%                     );
%                     app.ValveControllerComStatusStr = strcat( ...
%                         'Valve Controller -> ', ...
%                         app.Config.ValveControllerProtocol, ...
%                         ', remote: ', ...
%                         app.Config.ValveControllerRemoteAddress, ...
%                         ', port: ', ...
%                         num2str(app.Config.ValveControllerPort) ...
%                     );
%                     app.ValveControllerComStatusLabel.Text = app.ValveControllerComStatusStr;
%     %                     app.IsControllerReady = true;
%                 catch
%                     app.ValveControllerComStatusLabel.Text = "Connection with controller failed";
%                 end
            end
        end
        
        function Query(app, event, ~)
            % Query data from robot controller
            if convertCharsToStrings(app.Config.RobotControllerProtocol) == "Serial"
                count = floor(app.RobotControllerComObj.NumBytesAvailable/4);
            else
                count = floor(app.RobotControllerComObj.BytesAvailable/4);
            end
%             disp(num2str([count, app.RobotControllerComObj.NumBytesAvailable, event.InstantPeriod]));
%             disp(num2str([count, app.RobotControllerComObj.BytesAvailable]));
            if count >= app.Config.ControllerFrameSize
                app.ControllerQueriedData = read( ...
                    app.RobotControllerComObj, ...
                    fix(count/app.Config.ControllerFrameSize) * app.Config.ControllerFrameSize, ...
                    "single" ...
                );
                app.ControllerQueriedData = reshape(app.ControllerQueriedData, app.Config.ControllerFrameSize, [])';
                app.AssembleControllerDataBuffer(app.ControllerQueriedData);
                
                app.RXCount = app.RXCount + size(app.ControllerQueriedData, 1);
            end
            
            % Query data from FBG interrogator
            if app.IsFBGReady && app.RunButton.Value
            % if app.IsFBGReady
                count = floor(app.FBGComObj.BytesAvailable);
                if count > 0
                    app.FBGQueriedData = read(app.FBGComObj);
                    % disp(app.FBGQueriedData);
                    app.FBGQueriedData = textscan(char(app.FBGQueriedData), '%s', 'Delimiter', newline);
                    app.FBGQueriedData = char(app.FBGQueriedData{1});
                    LineNum = size(app.FBGQueriedData, 1);
                    app.FBGQueriedData = [app.FBGQueriedData, repmat(char(9), LineNum, 1)];
                    app.FBGQueriedData = reshape(sscanf(app.FBGQueriedData(:, 25:end)', '%f'), [], LineNum)';
                    % disp(num2str([count, size(app.FBGQueriedData, 1), event.InstantPeriod]));
                    % disp(app.FBGQueriedData);
                    app.AssembleFBGDataBuffer(app.FBGQueriedData);
                end
            end
        end
        
        function AssembleControllerDataBuffer(app, data)
            app.CmdEchoBuffer    = [app.CmdEchoBuffer; data(:, 1: 5)];
            app.UploadedStatus   = [app.UploadedStatus; data(:, 6)];
            app.DriverTimeBuffer = [app.DriverTimeBuffer; data(:, 7: 10)];
            app.SensorDataBuffer = [app.SensorDataBuffer; data(:, 11: 18)];
            app.ValveCmdBuffer   = [app.ValveCmdBuffer; data(:, 19)];
            % disp(app.SensorDataBuffer);
        end
        
        function AssembleFBGDataBuffer(app, data)
%             disp('FBGDataBuffer');
            % disp(app.FBGDataBuffer);
%             disp('data');
%             disp(data);
            app.FBGDataBuffer = [app.FBGDataBuffer; data(:, 9:12)];
            % disp(app.FBGDataBuffer);
        end
        
        function UIUpdate(app, ~, ~)
            % Return if no new data is received
            if size(app.DriverTimeBuffer, 1) == 0
%                 disp(app.RobotControllerComObj.NumBytesAvailable);
                disp("UI update failed.");
                return
            end
            
            % Update status bar
            app.DriverClockLabel.Text = strcat( ...
                'Driver time-- ', ...
                string(app.DriverTimeBuffer(end, 1)), " : ", ...
                string(app.DriverTimeBuffer(end, 2)), " : ", ...
                string(app.DriverTimeBuffer(end, 3)), " : ", ...
                string(round(app.DriverTimeBuffer(end, 4))) ...
            );
            app.RobotControllerComStatusLabel.Text = strcat( ...
                app.RobotControllerComStatusStr, ...
                ', rate: ', num2str(size(app.DriverTimeBuffer, 1)), ...
                ' frames/query, RX count: ', num2str(app.RXCount) ...
            );

            % Calculate x axis ticks
            ShiftLen = length(app.SensorDataBuffer(:, 1));
            if app.RXCount < app.UIBufferLen
                app.UIAxesxTicks((end - app.RXCount + 1): end) = linspace( ...
                    0, app.RXCount * app.Config.ControllerStepSize, ...
                    app.RXCount ...
                );
            else
                app.UIAxesxTicks = app.UIAxesxTicksFull;
            end
            
            % Update plot data
            % Draw motor position
            if app.PlotMotorAPosCheckBox.Value
                app.EncoderAPlotData = circshift(app.EncoderAPlotData, -ShiftLen);
                app.EncoderAPlotData((end - ShiftLen + 1): end) = app.SensorDataBuffer(:, 1);
            else
                app.EncoderAPlotData   = nan(1, app.UIBufferLen);
            end
            app.PositionPlot1.XData = app.UIAxesxTicks;
            app.PositionPlot1.YData = app.EncoderAPlotData;
            
            if app.PlotMotorBPosCheckBox.Value
                app.EncoderBPlotData = circshift(app.EncoderBPlotData, -ShiftLen);
                app.EncoderBPlotData((end - ShiftLen + 1): end) = app.SensorDataBuffer(:, 3);
            else
                app.EncoderBPlotData   = nan(1, app.UIBufferLen);
            end
            app.PositionPlot2.XData = app.UIAxesxTicks;
            app.PositionPlot2.YData = app.EncoderBPlotData;
            
            % Draw motor speed
%             if app.PlotMotorAPosCheckBox.Value
%                 app.SpeedAPlotData = circshift(app.SpeedAPlotData, -ShiftLen);
%                 app.SpeedAPlotData((end - ShiftLen + 1): end) = app.SensorDataBuffer(:, 2);
%                 app.PositionPlot1.XData = app.UIAxesxTicks;
%                 app.PositionPlot1.YData = app.SpeedAPlotData / (2*pi) * 60;
%             end
%             
%             if app.PlotMotorBPosCheckBox.Value
%                 app.SpeedBPlotData = circshift(app.SpeedBPlotData, -ShiftLen);
%                 app.SpeedBPlotData((end - ShiftLen + 1): end) = app.SensorDataBuffer(:, 4);
%                 app.PositionPlot2.XData = app.UIAxesxTicks;
%                 app.PositionPlot2.YData = app.SpeedBPlotData / (2*pi) * 60;
%             end
            
            % Draw armature current
            
            if app.AnalogACheckBox.Value
                app.CurrentAPlotData = circshift(app.CurrentAPlotData, -ShiftLen);
                app.CurrentAPlotData((end - ShiftLen + 1): end) = app.SensorDataBuffer(:, 5);
            else
                app.CurrentAPlotData   = nan(1, app.UIBufferLen);
            end
            app.CurrentPlot1.XData = app.UIAxesxTicks;
            app.CurrentPlot1.YData = app.CurrentAPlotData;

            if app.AnalogBCheckBox.Value
                app.CurrentBPlotData = circshift(app.CurrentBPlotData, -ShiftLen);
                app.CurrentBPlotData((end - ShiftLen + 1): end) = app.SensorDataBuffer(:, 6);
            else
                app.CurrentBPlotData   = nan(1, app.UIBufferLen);
            end
            app.CurrentPlot2.XData = app.UIAxesxTicks;
            app.CurrentPlot2.YData = app.CurrentBPlotData;
            
            if app.AnalogCCheckBox.Value
                app.CurrentCPlotData = circshift(app.CurrentCPlotData, -ShiftLen);
                app.CurrentCPlotData((end - ShiftLen + 1): end) = app.SensorDataBuffer(:, 7);
            else
                app.CurrentCPlotData   = nan(1, app.UIBufferLen);
            end
            app.CurrentPlot3.XData = app.UIAxesxTicks;
            app.CurrentPlot3.YData = app.CurrentCPlotData;
            
            if app.AnalogDCheckBox.Value
                app.CurrentDPlotData = circshift(app.CurrentDPlotData, -ShiftLen);
                app.CurrentDPlotData((end - ShiftLen + 1): end) = app.SensorDataBuffer(:, 8);
            else
                app.CurrentDPlotData   = nan(1, app.UIBufferLen);
            end
            app.CurrentPlot4.XData = app.UIAxesxTicks;
            app.CurrentPlot4.YData = app.CurrentDPlotData;
            
            % Draw FBG wavelength
            if app.IsFBGReady
                ShiftLen = length(app.FBGDataBuffer(:, 1));
                app.WavelengthAPlotData = circshift(app.WavelengthAPlotData, -ShiftLen);
                app.WavelengthAPlotData((end - ShiftLen + 1): end) = app.FBGDataBuffer(:, 1);
                % disp([size(app.UIAxesxTicks, 2), size(app.WavelengthAPlotData, 2)]);
                % disp(app.WavelengthAPlotData);
                app.WavelengthPlot1.XData = app.UIAxesxTicks;
                app.WavelengthPlot1.YData = app.WavelengthAPlotData;

                app.WavelengthBPlotData = circshift(app.WavelengthBPlotData, -ShiftLen);
                app.WavelengthBPlotData((end - ShiftLen + 1): end) = app.FBGDataBuffer(:, 2);
                app.WavelengthPlot2.XData = app.UIAxesxTicks;
                app.WavelengthPlot2.YData = app.WavelengthBPlotData;

                app.WavelengthCPlotData = circshift(app.WavelengthCPlotData, -ShiftLen);
                app.WavelengthCPlotData((end - ShiftLen + 1): end) = app.FBGDataBuffer(:, 3);
                app.WavelengthPlot3.XData = app.UIAxesxTicks;
                app.WavelengthPlot3.YData = app.WavelengthCPlotData;

                app.WavelengthDPlotData = circshift(app.WavelengthDPlotData, -ShiftLen);
                app.WavelengthDPlotData((end - ShiftLen + 1): end) = app.FBGDataBuffer(:, 4);
                app.WavelengthPlot4.XData = app.UIAxesxTicks;
                app.WavelengthPlot4.YData = app.WavelengthDPlotData;
            end
            
            drawnow limitrate
            
            % Save data to file
            DataToWrite = [app.CmdEchoBuffer, app.UploadedStatus, app.DriverTimeBuffer, app.SensorDataBuffer, app.ValveCmdBuffer];
            disp(size(app.FBGDataBuffer(1,:)));
            disp(size(DataToWrite));
            % DataToWrite = [app.CmdEchoBuffer, app.UploadedStatus, app.DriverTimeBuffer, app.SensorDataBuffer, app.ValveCmdBuffer, app.FBGDataBuffer(1,:)];
            fprintf(app.DataFileID, app.DataFormatStr, DataToWrite');

            % Clear data buffer
            app.ClearAppBuffer();
        end
        
        function SetupFBGApp(app, ~, ~)
            options = weboptions('Timeout', 2);
            
            % Return if launch fails
            cmd = ['start "" "', app.Config.FBGAppDirectory, '"'];
            if system(cmd)
                app.FBGComStatusStr = "FBG -> Interrogator application: launch failed";
                app.FBGComStatusLabel.Text = app.FBGComStatusStr;
                return
            end
            pause(4);
            
            % Check if application is online
            cmd = [app.FBGWebCmdPrefix, '/GetHWInformation'];
            % Detect if application reports problem
            try
                FBGReturn = webread(cmd, options);
                disp('data read');
                disp(FBGReturn);
                if FBGReturn.Error == 0 && FBGReturn.NumberOfChannels == 8
                    app.FBGComStatusStr = "FBG -> Interrogator application online";
                    app.FBGComStatusLabel.Text = app.FBGComStatusStr;
                    system('nircmd win min process "ILLumiSense.exe"');
                else
                    app.FBGComStatusStr = "FBG -> Interrogator problem detected";
                    app.FBGComStatusLabel.Text = app.FBGComStatusStr;
                    return
                end
            catch
                system('taskkill /F /IM ILLumiSense.exe >nul 2>&1');
                % launch again to bypass IRS power problem
                system(['start "" "', app.Config.FBGAppDirectory, '"']);
                pause(4);
                try
                    FBGReturn = webread(cmd, options);
                    if FBGReturn.Error == 0 && FBGReturn.NumberOfChannels == 8
                        app.FBGComStatusStr = "FBG -> Interrogator application online";
                        app.FBGComStatusLabel.Text = app.FBGComStatusStr;
                        system('nircmd win min process "ILLumiSense.exe"');
                    else
                        app.FBGComStatusStr = "FBG -> Interrogator problem detected";
                        app.FBGComStatusLabel.Text = app.FBGComStatusStr;
                        return
                    end
                catch
                    app.FBGComStatusStr = "FBG -> Interrogator not powered on";
                    app.FBGComStatusLabel.Text = app.FBGComStatusStr;
                    system('taskkill /F /IM ILLumiSense.exe >nul 2>&1');
                    return
                end
            end
            
            % Enable channels
            channel = 0;
            cmd = [app.FBGWebCmdPrefix, '/EnableChannel?Enable=1&Channel=', num2str(channel)];
            FBGReturn = webread(cmd, options);
            if FBGReturn.Error == 0
                app.FBGComStatusStr = strcat(app.FBGComStatusStr, ", enabled");
                app.FBGComStatusLabel.Text = app.FBGComStatusStr;
            else
                app.FBGComStatusStr = strcat(app.FBGComStatusStr, ", enable failed");
                app.FBGComStatusLabel.Text = app.FBGComStatusStr;
                return
            end
            
            % Auto set expected peaks
            pause(2);
            app.AutosetExpectedPeaks();
%             channel = 0;
%             cmd = [app.FBGWebCmdPrefix, '/AutoSetExpectedPeaks?Channel=', num2str(channel)];
%             FBGReturn = webread(cmd, options);
%             if FBGReturn.Error == 0 && FBGReturn.ExpectedPeaks == 1
%                 app.FBGComStatusStr = strcat(app.FBGComStatusStr, ", autoset");
%                 app.FBGComStatusLabel.Text = app.FBGComStatusStr;
%             else
%                 app.FBGComStatusStr = strcat(app.FBGComStatusStr, ", autoset failed");
%                 app.FBGComStatusLabel.Text = app.FBGComStatusStr;
%                 return
%             end
            
            % Set scan rate
            scanrate = app.Config.FBGScanRate;
            cmd = [app.FBGWebCmdPrefix, '/SetScanRate?SetScanRate=', num2str(scanrate)];
            FBGReturn = webread(cmd, options);
            if FBGReturn.Error == 0 && FBGReturn.ScanRate == scanrate
%                 app.FBGComStatusStr = strcat(app.FBGComStatusStr, ", scan rate: ", num2str(scanrate), " Hz");
%                 app.FBGComStatusLabel.Text = app.FBGComStatusStr;
                app.FBGComStatusStr = "FBG -> Ready";
                app.FBGComStatusLabel.Text = app.FBGComStatusStr;
                app.IsFBGReady = true;
            else
                app.FBGComStatusStr = strcat(app.FBGComStatusStr, ", scan rate set failed");
                app.FBGComStatusLabel.Text = app.FBGComStatusStr;
                return
            end
            
        end

        function TimeStr = GetCurrentTime(~)
            datetime.setDefaultFormats('default','yyyy-MM-dd HH:mm:ss');
            TimeStr = char(datetime);
            TimeStr = strrep(TimeStr, ':', '-');
        end
        
        
        function FlushBuffer(app, prot, obj)
            % Flush buffer
            if convertCharsToStrings(prot) == "Serial"
                % if using serial protocol, flush buffer
                flush(obj);
            else
                % if using TCP/IP, read all data in the buffer as flushing
                read(obj, obj.BytesAvailable);
            end
        end
        
        function ClearAppBuffer(app)
            app.CmdEchoBuffer    = [];
            app.UploadedStatus   = [];
            app.DriverTimeBuffer = [];
            app.SensorDataBuffer = [];
            app.ValveCmdBuffer   = [];
            app.FBGDataBuffer    = [];
            
            app.ValveUploadedStatus    = [];
            app.ValveControllerTimeBuffer = [];
        end
        
        function WriteToController(~, prot, obj, cmd)
            % Write data to the controller
            if convertCharsToStrings(prot) == "Serial"
                write(obj, cmd, "single");
            else
                write(obj, single(cmd));
            end
            pause(0.1);
        end
        
        
        function QueryValveController(app)
            % Query data from valve controller
            if convertCharsToStrings(app.Config.ValveControllerProtocol) == "Serial"
                count = floor(app.ValveControllerComObj.NumBytesAvailable/4);
            else
                count = floor(app.ValveControllerComObj.BytesAvailable/4);
            end
            disp(num2str([count, app.ValveControllerComObj.BytesAvailable]));
            if count >= app.Config.ControllerFrameSize
                app.ValveControllerQueriedData = read( ...
                    app.ValveControllerComObj, ...
                    fix(count/app.Config.ControllerFrameSize) * app.Config.ControllerFrameSize, ...
                    "single" ...
                );
                app.ValveControllerQueriedData = reshape(app.ValveControllerQueriedData, app.Config.ControllerFrameSize, [])';
                app.AssembleValveControllerDataBuffer(app.ValveControllerQueriedData);
                
%                 app.RXCount = app.RXCount + size(app.ControllerQueriedData, 1);
            end
        end
        
        function AssembleValveControllerDataBuffer(app, data)
            app.ValveUploadedStatus        = [app.ValveUploadedStatus; data(:, 1)];
            app.ValveControllerTimeBuffer = [app.ValveControllerTimeBuffer; data(:, 2: 5)];
        end
        
        function ResetPlotData(app)
            app.RXCount = 0;
            app.UIAxesxTicks       = nan(1, app.UIBufferLen);
            app.EncoderAPlotData   = nan(1, app.UIBufferLen);
            app.EncoderBPlotData   = nan(1, app.UIBufferLen);
            app.SpeedAPlotData     = nan(1, app.UIBufferLen);
            app.SpeedBPlotData     = nan(1, app.UIBufferLen);
            app.CurrentAPlotData   = nan(1, app.UIBufferLen);
            app.CurrentBPlotData   = nan(1, app.UIBufferLen);
            app.CurrentCPlotData   = nan(1, app.UIBufferLen);
            app.CurrentDPlotData   = nan(1, app.UIBufferLen);
            app.WavelengthAPlotData = nan(1, app.UIBufferLen);
            app.WavelengthBPlotData = nan(1, app.UIBufferLen);
            app.WavelengthCPlotData = nan(1, app.UIBufferLen);
            app.WavelengthDPlotData = nan(1, app.UIBufferLen);
        end
        
        function ReconnectRobotController(app)
            delete(app.RobotControllerComObj);
            if convertCharsToStrings(app.Config.RobotControllerProtocol) == "Serial"
                app.RobotControllerComStatusLabel.Text = "Serial reconnection not supported in this version";
            else
                try
                    app.RobotControllerComObj = tcpclient( ...
                        app.Config.RobotControllerRemoteAddress, ...
                        app.Config.RobotControllerPort, ...
                        'Timeout', 2 ...
                    );
                    app.RobotControllerComStatusStr = strcat( ...
                        'Robot Controller -> ', ...
                        app.Config.RobotControllerProtocol, ...
                        ', remote: ', ...
                        app.Config.RobotControllerRemoteAddress, ...
                        ', port: ', ...
                        num2str(app.Config.RobotControllerPort) ...
                    );
                    app.RobotControllerComStatusLabel.Text = app.RobotControllerComStatusStr;
                    app.IsControllerReady = true;
                catch
                    app.RobotControllerComStatusLabel.Text = "Reconnection with robot controller failed";
                end
            end
        end
        
        function AutosetExpectedPeaks(app)
            options = weboptions('Timeout', 2);
            channel = 0;
            cmd = [app.FBGWebCmdPrefix, '/AutoSetExpectedPeaks?Channel=', num2str(channel)];
            FBGReturn = webread(cmd, options);
            if FBGReturn.Error == 0 && FBGReturn.ExpectedPeaks == app.FBGCounts.Value
                app.FBGComStatusStr = strcat(app.FBGComStatusStr, ", autoset successful");
                app.FBGComStatusLabel.Text = app.FBGComStatusStr;
            else
                app.FBGComStatusStr = strcat(app.FBGComStatusStr, ", autoset failed");
                app.FBGComStatusLabel.Text = app.FBGComStatusStr;
                return
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load initialization file
            try
                 InitFile = load('./init.mat');
                 app.ConfigFileName = InitFile.ConfigFileName;
            catch
                uiwait(msgbox({'Initialization file not found'; 'Click OK to close the application'}));
                delete(app);
            end
            
            % Load configurations
            try
                app.Config = load(app.ConfigFileName);
            catch
                uiwait(msgbox({'Configuration file not found'; 'Click OK to load default configuration'}));
                
                % default configurations
                try
                    app.Config = load('./defaultConfig.mat');
                catch
                    uiwait(msgbox({'Default configuration file not found'; 'Click OK to close the application'}));
                    delete(app);
                end
            end
            
            % Create timer object
            app.QueryTimer = timer(...
                'ExecutionMode', 'fixedRate', ...            % Run timer repeatedly
                'Period', app.Config.QueryInterval, ...      % Period
                'BusyMode', 'drop',...                       % Queue timer callbacks when busy
                'TimerFcn', @app.Query);                     % Specify callback function
            app.UIUpdateTimer = timer(...
                'ExecutionMode', 'fixedRate', ...            % Run timer repeatedly
                'Period', app.Config.UIUpdateInterval, ...   % Period
                'BusyMode', 'queue',...                      % Queue timer callbacks when busy
                'TimerFcn', @app.UIUpdate);                  % Specify callback function
            app.SetupFBGAppTimer = timer(...
                'ExecutionMode', 'singleShot', ...
                'StartDelay', 1, ...
                'BusyMode', 'queue',...                      % Queue timer callbacks when busy
                'TimerFcn', @app.SetupFBGApp);      % Specify callback function
            
            % Update configurations
            app.UpdateConfig(app.Config);
            
            % Setup FBG interrogator application
            if app.Config.LaunchFBGAppAtStartup
                start(app.SetupFBGAppTimer);
            end
            
            % Initialize plot handles
            app.PositionPlot1  = plot(app.MotorPositionUIAxes,      nan, nan, 'LineWidth', app.Config.PlotLineWidth);
            app.PositionPlot2  = plot(app.MotorPositionUIAxes,      nan, nan, 'LineWidth', app.Config.PlotLineWidth);
            app.CurrentPlot1   = plot(app.CurrentTemperatureUIAxes, nan, nan, 'LineWidth', app.Config.PlotLineWidth);
            app.CurrentPlot2   = plot(app.CurrentTemperatureUIAxes, nan, nan, 'LineWidth', app.Config.PlotLineWidth);
            app.CurrentPlot3   = plot(app.CurrentTemperatureUIAxes, nan, nan, 'LineWidth', app.Config.PlotLineWidth);
            app.CurrentPlot4   = plot(app.CurrentTemperatureUIAxes, nan, nan, 'LineWidth', app.Config.PlotLineWidth);
            app.WavelengthPlot1 = plot(app.WavelengthUIAxes,         nan, nan, 'LineWidth', app.Config.PlotLineWidth);
            app.WavelengthPlot2 = plot(app.WavelengthUIAxes,         nan, nan, 'LineWidth', app.Config.PlotLineWidth);
            app.WavelengthPlot3 = plot(app.WavelengthUIAxes,         nan, nan, 'LineWidth', app.Config.PlotLineWidth);
            app.WavelengthPlot4 = plot(app.WavelengthUIAxes,         nan, nan, 'LineWidth', app.Config.PlotLineWidth);
            
            % Disable interactivity and auto calculated properties to
            % improve graphical performance
            disableDefaultInteractivity(app.MotorPositionUIAxes);
            disableDefaultInteractivity(app.CurrentTemperatureUIAxes);
            disableDefaultInteractivity(app.WavelengthUIAxes);
            app.MotorPositionUIAxes.Toolbar.Visible = 'off';
            app.CurrentTemperatureUIAxes.Toolbar.Visible = 'off';
            app.WavelengthUIAxes.Toolbar.Visible = 'off';
            
%             profile on;
            % Get current directory and put in the directory edit field
            app.DirectoryEditField.Value = pwd;
        end

        % Menu selected function: SettingsMenu
        function SettingsMenuSelected(app, event)
            app.SettingApp = setting(app, app.Config);
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            About();
        end

        % Close request function: ActuatorConsoleUIFigure
        function ActuatorConsoleUIFigureCloseRequest(app, event)
            % Stop all timers
            stop(app.UIUpdateTimer);
            stop(app.QueryTimer);
            
            % Send a stop code to the controllers
%             app.FlushBuffer(app.Config.RobotControllerProtocol, app.RobotControllerComObj);
            app.WriteToController(app.Config.RobotControllerProtocol, app.RobotControllerComObj, [app.CmdTurnOff, 0, 0, 0, 0]);
%             app.WriteToController(app.Config.ValveControllerProtocol, app.ValveControllerComObj, [ones(1, app.Config.ControllerFrameSize-1), app.CmdTurnOff]);
            
            % Delete all created objects
            delete(app.UIUpdateTimer);
            delete(app.QueryTimer);
            delete(app.SetupFBGAppTimer);
            delete(app.RobotControllerComObj);
            delete(app.FBGComObj);
            
            % Kill FBG interrogator application
            system('nircmd win max process "ILLumiSense.exe"');
            system('taskkill /F /IM ILLumiSense.exe >nul 2>&1');
            
            delete(app);
            
%             profile viewer;
        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            if app.AutomaticButton.Value
                mode = app.OperationModeDropDown.Value;
                IdleCode = 52;
                if mode == "Position"
                    SetCmd     = 14;
                    StatusCode = 53;
                    ResetCmd   = 15;
                    InitValue  = 0;
                elseif mode == "Speed"
                    SetCmd     = 100;
                    StatusCode = 100;
                    ResetCmd   = 100;
                    InitValue  = 0;
                elseif mode == "Temperature"
                    SetCmd     = 18;
                    StatusCode = 55;
                    ResetCmd   = 19;
                    InitValue  = 25;
                elseif mode == "Open loop (PWM)"
                    SetCmd     = 20;
                    StatusCode = 56;
                    ResetCmd   = 21;
                    InitValue  = 0;
                elseif mode == "Pre-cool"
                    SetCmd     = 22;
                    StatusCode = 60;
                    ResetCmd   = 23;
                    InitValue  = 0;
                else
                    SetCmd     = 100;
                    StatusCode = 100;
                    ResetCmd   = 100;
                    InitValue  = 0;
                end
                
                if app.RunButton.Value
                    % Get file name
                    if app.TestnameEditField.Value == ""
                        DataFileName = [app.GetCurrentTime(), ' data file.txt'];
                    else
                        DataFileName = [app.GetCurrentTime(), ' ', app.TestnameEditField.Value, '.txt'];
                    end
                    % Get file directory
                    DataFileDir = app.DirectoryEditField.Value;
                    % Create data file writer
                    app.DataFileID = fopen([DataFileDir, '\', DataFileName], 'w');
                    
                    % Turn on FBG interrogator data flow and create com object
                    if app.IsFBGReady
                        FBGReturn = webread([app.FBGWebCmdPrefix, '/EnableTCP?Enable=1']);
                        if FBGReturn.Error == 0 && FBGReturn.Status == 1
                            % Connection attempt to FBG interrogator
                            try
                                app.FBGComObj = tcpclient(app.Config.FBGRemoteAddress, app.Config.FBGDataStreamPort, 'Timeout', 2);
                                app.FBGComStatusLabel.Text = strcat(app.FBGComStatusStr, ", TCP stream on");
                            catch
                                app.FBGComStatusLabel.Text = strcat(app.FBGComStatusStr, ", TCP connection failed");
                            end
                        else
                            app.FBGComStatusLabel.Text = strcat(app.FBGComStatusStr, ", TCP stream failed");
                        end
                    end
                    
                    % Clear axes data in the graph
                    app.ResetPlotData();
                    
                    % Flush buffer then start the data streaming from controller
                    app.ClearAppBuffer();
                    app.FlushBuffer(app.Config.RobotControllerProtocol, app.RobotControllerComObj);
                    app.WriteToController(app.Config.RobotControllerProtocol, app.RobotControllerComObj, ...
                        [SetCmd, InitValue, InitValue, InitValue, InitValue]);
%                     app.Query();
%                     disp(app.UploadedStatus);
                    
                    start(app.QueryTimer);
                    start(app.UIUpdateTimer);
                elseif ~app.RunButton.Value
                    stop(app.QueryTimer);
                    stop(app.UIUpdateTimer);
                    
                    % Stop controller data streaming
                    fclose(app.DataFileID);
                    app.ClearAppBuffer();
                    app.FlushBuffer(app.Config.RobotControllerProtocol, app.RobotControllerComObj);
                    app.WriteToController(app.Config.RobotControllerProtocol, app.RobotControllerComObj, ...
                        [ResetCmd, InitValue, InitValue, InitValue, InitValue]);
%                     app.Query();
%                     disp(app.UploadedStatus);
                    
                    % Turn off FBG interrogator data stream and delete com object
                    if app.IsFBGReady
                        FBGReturn = webread([app.FBGWebCmdPrefix, '/EnableTCP?Enable=0']);
                        if FBGReturn.Error == 0 && FBGReturn.Status == 0
                            app.FBGComStatusLabel.Text = strcat(app.FBGComStatusStr, ", TCP stream off");
                        end
                        delete(app.FBGComObj);
                    end
                end
            elseif app.SignleShotButton.Value
                app.Query();
                app.RunButton.Value = false;
                app.UIUpdate();
            end
        end

        % Value changed function: ShowFBGAppWindowButton
        function ShowFBGAppWindowButtonValueChanged(app, event)
            value = app.ShowFBGAppWindowButton.Value;
            if value
                system('nircmd win max process "ILLumiSense.exe"');
            else
                system('nircmd win min process "ILLumiSense.exe"');
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value, 'On')
                app.FlushBuffer(app.Config.RobotControllerProtocol, app.RobotControllerComObj);
                app.WriteToController(app.Config.RobotControllerProtocol, app.RobotControllerComObj, [app.CmdOff2On, 0, 0, 0, 0]);
                app.Query();
%                 disp(app.UploadedStatus);
                if app.UploadedStatus == 51
                    app.RobotControllerLamp.Color = [1.00,1.00,0.07];
                    app.ValveControllerLamp.Color = [1.00,1.00,0.07];
                end
                
%                 app.FlushBuffer(app.Config.ValveControllerProtocol, app.ValveControllerComObj);
%                 app.WriteToController(app.Config.ValveControllerProtocol, app.ValveControllerComObj, [ones(1, app.Config.ControllerFrameSize-1), app.CmdOff2On]);
%                 app.QueryValveController();
%                 disp(app.ValveUploadedStatus);
%                 if app.ValveUploadedStatus == 51
%                     app.ValveControllerLamp.Color = [1.00,1.00,0.07];
%                 end
                
                app.ClearAppBuffer();
            else
                app.FlushBuffer(app.Config.RobotControllerProtocol, app.RobotControllerComObj);
                app.WriteToController(app.Config.RobotControllerProtocol, app.RobotControllerComObj, [app.CmdOn2Off, 0, 0, 0, 0]);
                app.Query();
%                 disp(app.UploadedStatus);
                if app.UploadedStatus == 50
                    app.RobotControllerLamp.Color = [0.65,0.65,0.65];
                    app.ValveControllerLamp.Color = [0.65,0.65,0.65];
                end
                
%                 app.FlushBuffer(app.Config.ValveControllerProtocol, app.ValveControllerComObj);
%                 app.WriteToController(app.Config.ValveControllerProtocol, app.ValveControllerComObj, [ones(1, app.Config.ControllerFrameSize-1), app.CmdOn2Off]);
%                 app.QueryValveController();
%                 disp(app.ValveUploadedStatus);
%                 if app.ValveUploadedStatus == 50
%                     app.ValveControllerLamp.Color = [0.65,0.65,0.65];
%                 end
                
                app.ClearAppBuffer();
            end
        end

        % Value changed function: EnableSwitch
        function EnableSwitchValueChanged(app, event)
            value = app.EnableSwitch.Value;
            if strcmp(value, 'On')
                app.FlushBuffer(app.Config.RobotControllerProtocol, app.RobotControllerComObj);
                app.WriteToController(app.Config.RobotControllerProtocol, app.RobotControllerComObj, [app.CmdOn2Enable, 0, 0, 0, 0]);
                app.Query();
%                 disp(app.UploadedStatus);
                if app.UploadedStatus == 52
                    app.RobotControllerLamp.Color = [0.85,0.33,0.1];
                    app.ValveControllerLamp.Color = [0.85,0.33,0.1];
                    app.RunButton.Enable = true;
                    app.CmdSendButton.Enable = true;
                end
                
%                 app.FlushBuffer(app.Config.ValveControllerProtocol, app.ValveControllerComObj);
%                 app.WriteToController(app.Config.ValveControllerProtocol, app.ValveControllerComObj, [ones(1, app.Config.ControllerFrameSize-1), app.CmdOn2Enable]);
% %                 disp(num2str([app.ValveControllerComObj.BytesAvailable]));
%                 app.QueryValveController();
% %                 disp(app.ValveUploadedStatus);
%                 if app.ValveUploadedStatus == 52
%                     app.ValveControllerLamp.Color = [0.85,0.33,0.1];
%                     app.CmdSendButton.Enable = true;
%                 end
                
                app.ClearAppBuffer();
            else
                app.ClearAppBuffer();
                app.FlushBuffer(app.Config.RobotControllerProtocol, app.RobotControllerComObj);
                app.WriteToController(app.Config.RobotControllerProtocol, app.RobotControllerComObj, [app.CmdEnable2On, 0, 0, 0, 0]);
                app.Query();
%                 disp(app.UploadedStatus);
                if app.UploadedStatus == 51
                    app.RobotControllerLamp.Color = [1.00,1.00,0.07];
                    app.ValveControllerLamp.Color = [1.00,1.00,0.07];
                    app.RunButton.Enable = false;
                    app.CmdSendButton.Enable = false;
                end
                
%                 app.FlushBuffer(app.Config.ValveControllerProtocol, app.ValveControllerComObj);
%                 app.WriteToController(app.Config.ValveControllerProtocol, app.ValveControllerComObj, [ones(1, app.Config.ControllerFrameSize-1), app.CmdEnable2On]);
% %                 disp(num2str([app.ValveControllerComObj.BytesAvailable]));
%                 app.QueryValveController();
% %                 disp(app.ValveUploadedStatus);
%                 if app.ValveUploadedStatus == 51
%                     app.ValveControllerLamp.Color = [1.00,1.00,0.07];
%                     app.CmdSendButton.Enable = false;
%                 end
                
                app.ClearAppBuffer();
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            path = uigetdir(pwd, 'Select directory to save data file');
            figure(app.ActuatorConsoleUIFigure)
            app.DirectoryEditField.Value = path;
        end

        % Value changed function: CmdSendButton
        function CmdSendButtonValueChanged(app, event)
            value = app.CmdSendButton.Value;

            % code used when valve controller works as tcp server
%             if value
%                 app.FlushBuffer(app.Config.ValveControllerProtocol, app.ValveControllerComObj);
%                 app.WriteToController(app.Config.ValveControllerProtocol, app.ValveControllerComObj, [14, 0, 0]);
% %                 disp(num2str([app.ValveControllerComObj.BytesAvailable]));
%                 app.QueryValveController();
% %                 disp(app.ValveUploadedStatus);
%                 if app.ValveUploadedStatus == 53
%                     app.ValveControllerLamp.Color = [0.07,0.62,1.00];
%                 end
%                 
%                 app.ClearAppBuffer();
%             elseif ~value
%                 app.FlushBuffer(app.Config.ValveControllerProtocol, app.ValveControllerComObj);
%                 app.WriteToController(app.Config.ValveControllerProtocol, app.ValveControllerComObj, [15, 0, 0]);
% %                 disp(num2str([app.ValveControllerComObj.BytesAvailable]));
%                 app.QueryValveController();
% %                 disp(app.ValveUploadedStatus);
%                 if app.ValveUploadedStatus == 52
%                     app.ValveControllerLamp.Color = [0.85,0.33,0.1];
%                 end
%                 
%                 app.ClearAppBuffer();
%             end

            if value
                app.CmdSendButton.Value = false;
                
%                 if app.OperationModeDropDown.Value == "Position"
%                     CmdArray = [100, app.Cmd1EditField.Value / 180 * pi, app.Cmd2EditField.Value / 180 * pi, 0, 0];
%                 else
%                     CmdArray = [100, app.Cmd1EditField.Value, app.Cmd2EditField.Value, app.Cmd3EditField.Value, app.Cmd4EditField.Value];
%                 end
                
                app.FlushBuffer(app.Config.RobotControllerProtocol, app.RobotControllerComObj);
                app.WriteToController(app.Config.RobotControllerProtocol, app.RobotControllerComObj, ...
                    [100, app.Cmd1EditField.Value, app.Cmd2EditField.Value, app.Cmd3EditField.Value, app.Cmd4EditField.Value]);
%                 disp(num2str([app.RobotControllerComObj.BytesAvailable]));
%                 app.Query();
%                 disp(app.UploadedStatus);
                
%                 app.ClearAppBuffer();
            end
        end

        % Button pushed function: ReconnectButton
        function ReconnectButtonPushed(app, event)
            app.ReconnectRobotController();
        end

        % Button pushed function: FBGAutoset
        function FBGAutosetButtonPushed(app, event)
            app.AutosetExpectedPeaks();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ActuatorConsoleUIFigure = uifigure('Visible', 'off');
            app.ActuatorConsoleUIFigure.Name = 'Actuator Console';
            app.ActuatorConsoleUIFigure.CloseRequestFcn = createCallbackFcn(app, @ActuatorConsoleUIFigureCloseRequest, true);
            app.ConfigurationMenu = uimenu(app.ActuatorConsoleUIFigure);
            app.ConfigurationMenu.Text = 'Configuration';
            app.SettingsMenu = uimenu(app.ConfigurationMenu);
            app.SettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @SettingsMenuSelected, true);
            app.SettingsMenu.Text = 'Settings';
            app.ToolsMenu = uimenu(app.ActuatorConsoleUIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.DataReplayMenu = uimenu(app.ToolsMenu);
            app.DataReplayMenu.Text = 'Data Replay';
            app.HelpMenu = uimenu(app.ActuatorConsoleUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.TabGroup = uitabgroup(app.ActuatorConsoleUIFigure);
            app.ActuatorTab = uitab(app.TabGroup);
            app.ActuatorTab.Title = 'Actuator';
            app.MotorPositionUIAxes = uiaxes(app.ActuatorTab);
            title(app.MotorPositionUIAxes, 'Position')
            xlabel(app.MotorPositionUIAxes, 'Time (s)')
            ylabel(app.MotorPositionUIAxes, 'Angle (deg)')
            app.MotorPositionUIAxes.XLim = [0 20];
            app.CurrentTemperatureUIAxes = uiaxes(app.ActuatorTab);
            title(app.CurrentTemperatureUIAxes, 'Temperature / Current')
            xlabel(app.CurrentTemperatureUIAxes, 'Time (s)')
            ylabel(app.CurrentTemperatureUIAxes, 'T (degC) / Current (mA)')
            app.CurrentTemperatureUIAxes.XLim = [0 20];
            app.PlotMotorAPosCheckBox = uicheckbox(app.ActuatorTab);
            app.PlotMotorAPosCheckBox.Text = 'Channel A';
            app.PlotMotorAPosCheckBox.Value = true;
            app.PlotMotorBPosCheckBox = uicheckbox(app.ActuatorTab);
            app.PlotMotorBPosCheckBox.Text = 'Channel B';
            app.PlotMotorBPosCheckBox.Value = true;
            app.LaunchPadPanel = uipanel(app.ActuatorTab);
            app.LaunchPadPanel.Title = 'Launch Pad';
            app.RunButton = uibutton(app.LaunchPadPanel, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.SwitchLabel = uilabel(app.LaunchPadPanel);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.LaunchPadPanel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.RobotControllerLampLabel = uilabel(app.LaunchPadPanel);
            app.RobotControllerLampLabel.Text = 'Robot Controller';
            app.RobotControllerLamp = uilamp(app.LaunchPadPanel);
            app.EnableSwitchLabel = uilabel(app.LaunchPadPanel);
            app.EnableSwitchLabel.Text = 'Enable';
            app.EnableSwitch = uiswitch(app.LaunchPadPanel, 'slider');
            app.EnableSwitch.ValueChangedFcn = createCallbackFcn(app, @EnableSwitchValueChanged, true);
            app.LoggingControlButtonGroup = uibuttongroup(app.LaunchPadPanel);
            app.LoggingControlButtonGroup.Title = 'Logging Control';
            app.SignleShotButton = uiradiobutton(app.LoggingControlButtonGroup);
            app.SignleShotButton.Text = 'Signle-Shot';
            app.AutomaticButton = uiradiobutton(app.LoggingControlButtonGroup);
            app.AutomaticButton.Text = 'Automatic';
            app.AutomaticButton.Value = true;
            app.TestnameEditFieldLabel = uilabel(app.LoggingControlButtonGroup);
            app.TestnameEditFieldLabel.Text = 'Test name';
            app.TestnameEditField = uieditfield(app.LoggingControlButtonGroup, 'text');
            app.DirectoryEditFieldLabel = uilabel(app.LoggingControlButtonGroup);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.LoggingControlButtonGroup, 'text');
            app.DirectoryEditField.Value = './';
            app.BrowseButton = uibutton(app.LoggingControlButtonGroup, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.ValveControllerLampLabel = uilabel(app.LaunchPadPanel);
            app.ValveControllerLampLabel.Text = 'Valve Controller';
            app.ValveControllerLamp = uilamp(app.LaunchPadPanel);
            app.CmdSendButton = uibutton(app.LaunchPadPanel, 'state');
            app.CmdSendButton.ValueChangedFcn = createCallbackFcn(app, @CmdSendButtonValueChanged, true);
            app.CmdSendButton.Enable = 'off';
            app.CmdSendButton.Text = 'Send';
            app.Command1EditFieldLabel = uilabel(app.LaunchPadPanel);
            app.Command1EditFieldLabel.Text = 'Command 1';
            app.Cmd1EditField = uieditfield(app.LaunchPadPanel, 'numeric');
            app.Command2EditFieldLabel = uilabel(app.LaunchPadPanel);
            app.Command2EditFieldLabel.Text = 'Command 2';
            app.Cmd2EditField = uieditfield(app.LaunchPadPanel, 'numeric');
            app.ModeDropDownLabel = uilabel(app.LaunchPadPanel);
            app.ModeDropDownLabel.Text = 'Mode';
            app.OperationModeDropDown = uidropdown(app.LaunchPadPanel);
            app.OperationModeDropDown.Items = {'Position', 'Speed', 'Temperature', 'Open loop (PWM)', 'Pre-cool'};
            app.OperationModeDropDown.Value = 'Position';
            app.Command3Label = uilabel(app.LaunchPadPanel);
            app.Command3Label.Text = 'Command 3';
            app.Cmd3EditField = uieditfield(app.LaunchPadPanel, 'numeric');
            app.Command4Label = uilabel(app.LaunchPadPanel);
            app.Command4Label.Text = 'Command 4';
            app.Cmd4EditField = uieditfield(app.LaunchPadPanel, 'numeric');
            app.KpEditFieldLabel = uilabel(app.LaunchPadPanel);
            app.KpEditFieldLabel.Text = 'Kp';
            app.KpEditField = uieditfield(app.LaunchPadPanel, 'numeric');
            app.KiEditFieldLabel = uilabel(app.LaunchPadPanel);
            app.KiEditFieldLabel.Text = 'Ki';
            app.KiEditField = uieditfield(app.LaunchPadPanel, 'numeric');
            app.KdEditFieldLabel = uilabel(app.LaunchPadPanel);
            app.KdEditFieldLabel.Text = 'Kd';
            app.KdEditField = uieditfield(app.LaunchPadPanel, 'numeric');
            app.TuneButton = uibutton(app.LaunchPadPanel, 'state');
            app.TuneButton.Text = 'Tune';
            app.AnalogACheckBox = uicheckbox(app.ActuatorTab);
            app.AnalogACheckBox.Text = 'Analog A';
            app.AnalogACheckBox.Value = true;
            app.AnalogBCheckBox = uicheckbox(app.ActuatorTab);
            app.AnalogBCheckBox.Text = 'Analog B';
            app.AnalogBCheckBox.Value = true;
            app.AnalogCCheckBox = uicheckbox(app.ActuatorTab);
            app.AnalogCCheckBox.Text = 'Analog C';
            app.AnalogCCheckBox.Value = true;
            app.AnalogDCheckBox = uicheckbox(app.ActuatorTab);
            app.AnalogDCheckBox.Text = 'Analog D';
            app.AnalogDCheckBox.Value = true;
            app.FBGTab = uitab(app.TabGroup);
            app.FBGTab.Title = 'FBG';
            app.WavelengthUIAxes = uiaxes(app.FBGTab);
            title(app.WavelengthUIAxes, 'Wavelength')
            xlabel(app.WavelengthUIAxes, 'Time (s)')
            ylabel(app.WavelengthUIAxes, 'pm')
            app.WavelengthUIAxes.XLim = [0 20];
            app.WavelengthUIAxes.XTickLabel = {'0'; '2'; '4'; '6'; '8'; '10'; '12'; '14'; '16'; '18'; '20'};
            app.ShowFBGAppWindowButton = uibutton(app.FBGTab, 'state');
            app.ShowFBGAppWindowButton.ValueChangedFcn = createCallbackFcn(app, @ShowFBGAppWindowButtonValueChanged, true);
            app.ShowFBGAppWindowButton.Text = 'Show FBG App Window';
            app.NumberofGratingsLabel = uilabel(app.FBGTab);
            app.NumberofGratingsLabel.Text = 'Number of Gratings';
            app.FBGCounts = uieditfield(app.FBGTab, 'text');
            app.FBGCounts.Value = '4';
            app.RobotControllerComStatusLabel = uilabel(app.ActuatorConsoleUIFigure);
            app.RobotControllerComStatusLabel.Text = 'Robot Controller -> Not configured';
            app.DriverClockLabel = uilabel(app.ActuatorConsoleUIFigure);
            app.DriverClockLabel.Text = 'Driver time--NaN';
            app.FBGComStatusLabel = uilabel(app.ActuatorConsoleUIFigure);
            app.FBGComStatusLabel.Text = 'FBG -> Not configured';
            app.ValveControllerComStatusLabel = uilabel(app.ActuatorConsoleUIFigure);
            app.ValveControllerComStatusLabel.Text = 'Valve Controller -> Not configured';
            app.ReconnectButton = uibutton(app.ActuatorConsoleUIFigure, 'push');
            app.ReconnectButton.ButtonPushedFcn = createCallbackFcn(app, @ReconnectButtonPushed, true);
            app.ReconnectButton.Text = 'Reconnect';
            app.FBGAutoset = uibutton(app.ActuatorConsoleUIFigure, 'push');
            app.FBGAutoset.ButtonPushedFcn = createCallbackFcn(app, @FBGAutosetButtonPushed, true);
            app.FBGAutoset.Text = 'Autoset';
            % Show the figure after all components are created
            app.ActuatorConsoleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = actuator_console

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ActuatorConsoleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ActuatorConsoleUIFigure)
        end
    end
end