classdef setting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SettingUIFigure                 matlab.ui.Figure
        OKButton                        matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        GeneralTab                      matlab.ui.container.Tab
        UpdateRateControlPanel          matlab.ui.container.Panel
        QueryintervalinsEditFieldLabel  matlab.ui.control.Label
        QueryIntervalEditField          matlab.ui.control.NumericEditField
        UIupdateintervalinsEditFieldLabel  matlab.ui.control.Label
        UIUpdateIntervalEditField       matlab.ui.control.NumericEditField
        GraphicsControlPanel            matlab.ui.container.Panel
        UIaxislimitinsEditFieldLabel    matlab.ui.control.Label
        UIAxisLimitEditField            matlab.ui.control.NumericEditField
        LinewidthEditFieldLabel         matlab.ui.control.Label
        PlotLineWidthEditField          matlab.ui.control.NumericEditField
        ControllersTab                  matlab.ui.container.Tab
        CommRobotPanel                  matlab.ui.container.Panel
        PortLabel                       matlab.ui.control.Label
        RobotControllerCOMDropDown      matlab.ui.control.DropDown
        ProtocolDropDownLabel           matlab.ui.control.Label
        RobotControllerProtocolDropDown  matlab.ui.control.DropDown
        RemoteAddressEditFieldLabel     matlab.ui.control.Label
        RobotControllerRemoteAddressEditField  matlab.ui.control.EditField
        BaudRateEditField_2Label        matlab.ui.control.Label
        RobotControllerBaudRateEditField  matlab.ui.control.NumericEditField
        PortEditField_2Label            matlab.ui.control.Label
        RobotControllerPortEditField    matlab.ui.control.NumericEditField
        ParametersPanel                 matlab.ui.container.Panel
        StepsizeinsLabel                matlab.ui.control.Label
        ControllerStepSizeEditField     matlab.ui.control.NumericEditField
        FrameSizeEditFieldLabel         matlab.ui.control.Label
        ControllerFrameSizeEditField    matlab.ui.control.NumericEditField
        CommValvePanel                  matlab.ui.container.Panel
        PortLabel_2                     matlab.ui.control.Label
        ValveControllerCOMDropDown      matlab.ui.control.DropDown
        ProtocolDropDownLabel_2         matlab.ui.control.Label
        ValveControllerProtocolDropDown  matlab.ui.control.DropDown
        RemoteAddressEditFieldLabel_2   matlab.ui.control.Label
        ValveControllerRemoteAddressEditField  matlab.ui.control.EditField
        BaudRateEditField_2Label_2      matlab.ui.control.Label
        ValveControllerBaudRateEditField  matlab.ui.control.NumericEditField
        PortEditField_2Label_2          matlab.ui.control.Label
        ValveControllerPortEditField    matlab.ui.control.NumericEditField
        FBGTab                          matlab.ui.container.Tab
        FBGTCPIPPanel                   matlab.ui.container.Panel
        RemoteAddressEditField_2Label   matlab.ui.control.Label
        FBGRemoteAddressEditField       matlab.ui.control.EditField
        ControlportEditFieldLabel       matlab.ui.control.Label
        FBGControlPortEditField         matlab.ui.control.NumericEditField
        DatastreamingportLabel_2        matlab.ui.control.Label
        FBGDataStreamPortEditField      matlab.ui.control.NumericEditField
        ScanrateinHzEditFieldLabel      matlab.ui.control.Label
        FBGScanRateEditField            matlab.ui.control.NumericEditField
        FBGAppPanel                     matlab.ui.container.Panel
        FBGAppDirBrowseButton           matlab.ui.control.Button
        InterrogatorapplicationdirectoryEditFieldLabel  matlab.ui.control.Label
        FBGAppDirEditField              matlab.ui.control.EditField
        LaunchFBGAppAtStartupCheckBox   matlab.ui.control.CheckBox
        ApplyButton                     matlab.ui.control.Button
        LoadConfigButton                matlab.ui.control.Button
        SaveConfigButton                matlab.ui.control.Button
        SaveSetdefaultButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Main app object
        AvailablePorts
        
        Config
        SaveConfigFileName
        FBGAppDir
    end
    
    methods (Access = private)
        
        function ApplySettings(app)
            % Update communication configurations
            UpdateConfig(app.CallingApp, app.GetherProperties());
        end
        
        function config = GetherProperties(app)
            config.QueryInterval    = app.QueryIntervalEditField.Value;
            config.UIUpdateInterval = app.UIUpdateIntervalEditField.Value;
            config.UIAxisXLim       = app.UIAxisLimitEditField.Value;
            config.PlotLineWidth    = app.PlotLineWidthEditField.Value;
            
            config.RobotControllerProtocol      = app.RobotControllerProtocolDropDown.Value;
            config.RobotControllerCOM           = app.RobotControllerCOMDropDown.Value;
            config.RobotControllerBaudRate      = app.RobotControllerBaudRateEditField.Value;
            config.RobotControllerRemoteAddress = app.RobotControllerRemoteAddressEditField.Value;
            config.RobotControllerPort          = app.RobotControllerPortEditField.Value;
            
            config.ValveControllerProtocol      = app.ValveControllerProtocolDropDown.Value;
            config.ValveControllerCOM           = app.ValveControllerCOMDropDown.Value;
            config.ValveControllerBaudRate      = app.ValveControllerBaudRateEditField.Value;
            config.ValveControllerRemoteAddress = app.ValveControllerRemoteAddressEditField.Value;
            config.ValveControllerPort          = app.ValveControllerPortEditField.Value;
            
            config.ControllerStepSize           = app.ControllerStepSizeEditField.Value;
            config.ControllerFrameSize          = app.ControllerFrameSizeEditField.Value;
            
            config.FBGRemoteAddress      = app.FBGRemoteAddressEditField.Value;
            config.FBGDataStreamPort     = app.FBGDataStreamPortEditField.Value;
            config.FBGControlPort        = app.FBGControlPortEditField.Value;
            config.FBGScanRate           = app.FBGScanRateEditField.Value;
            config.LaunchFBGAppAtStartup = app.LaunchFBGAppAtStartupCheckBox.Value;
            config.FBGAppDirectory       = app.FBGAppDirEditField.Value;
        end
        
        function DistributeProperties(app, config)
            app.QueryIntervalEditField.Value    = config.QueryInterval;
            app.UIUpdateIntervalEditField.Value = config.UIUpdateInterval;
            app.UIAxisLimitEditField.Value      = config.UIAxisXLim;
            app.PlotLineWidthEditField.Value    = config.PlotLineWidth;
            
            app.RobotControllerProtocolDropDown.Value = config.RobotControllerProtocol;
            if convertCharsToStrings(config.RobotControllerProtocol) == "Serial"
                % If using serial protocol
                if ~any(strcmp(app.RobotControllerCOMDropDown.Items, convertCharsToStrings(config.RobotControllerCOM)))
                    app.RobotControllerCOMDropDown.Items{end + 1} = config.RobotControllerCOM;
                end
                app.RobotControllerCOMDropDown.Value       = config.RobotControllerCOM;
                app.RobotControllerBaudRateEditField.Value = config.RobotControllerBaudRate;
                
                app.RobotProtocolUIChange('Serial');
            else
                % If using TCP/IP protocol
                app.RobotControllerRemoteAddressEditField.Value = config.RobotControllerRemoteAddress;
                app.RobotControllerPortEditField.Value          = config.RobotControllerPort;
                
                app.RobotProtocolUIChange('TCP/IP');
            end

            app.ControllerStepSizeEditField.Value           = config.ControllerStepSize;
            app.ControllerFrameSizeEditField.Value          = config.ControllerFrameSize;
            
            app.FBGRemoteAddressEditField.Value     = config.FBGRemoteAddress;
            app.FBGDataStreamPortEditField.Value    = config.FBGDataStreamPort;
            app.FBGControlPortEditField.Value       = config.FBGControlPort;
            app.FBGScanRateEditField.Value          = config.FBGScanRate;
            app.LaunchFBGAppAtStartupCheckBox.Value = config.LaunchFBGAppAtStartup;
            app.FBGAppDirEditField.Value            = config.FBGAppDirectory;
        end
        
        function RobotProtocolUIChange(app, mode)
            if convertCharsToStrings(mode) == "Serial"
                app.RobotControllerRemoteAddressEditField.Enable = false;
                app.RobotControllerPortEditField.Enable = false;
                app.RobotControllerCOMDropDown.Enable = true;
                app.RobotControllerBaudRateEditField.Enable = true;
            elseif convertCharsToStrings(mode) == "TCP/IP"
                app.RobotControllerRemoteAddressEditField.Enable = true;
                app.RobotControllerPortEditField.Enable = true;
                app.RobotControllerCOMDropDown.Enable = false;
                app.RobotControllerBaudRateEditField.Enable = false;
            end
        end
        
        function ValveProtocolUIChange(app, mode)
            if convertCharsToStrings(mode) == "Serial"
                app.ValveControllerRemoteAddressEditField.Enable = false;
                app.ValveControllerPortEditField.Enable = false;
                app.ValveControllerCOMDropDown.Enable = true;
                app.ValveControllerBaudRateEditField.Enable = true;
            elseif convertCharsToStrings(mode) == "TCP/IP"
                app.ValveControllerRemoteAddressEditField.Enable = true;
                app.ValveControllerPortEditField.Enable = true;
                app.ValveControllerCOMDropDown.Enable = false;
                app.ValveControllerBaudRateEditField.Enable = false;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, config)
            app.CallingApp = mainapp;
            
            app.AvailablePorts = fliplr(serialportlist("all"));
            app.RobotControllerCOMDropDown.Items = app.AvailablePorts;
            app.RobotControllerCOMDropDown.Value = app.AvailablePorts(1);
            
            app.DistributeProperties(config);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.ApplySettings();
            
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: RobotControllerProtocolDropDown
        function RobotControllerProtocolDropDownValueChanged(app, event)
            value = app.RobotControllerProtocolDropDown.Value;
            if value == "Serial"
                app.RobotProtocolUIChange('Serial');
            else
                app.RobotProtocolUIChange('TCP/IP');
            end
        end

        % Button pushed function: FBGAppDirBrowseButton
        function FBGAppDirBrowseButtonPushed(app, event)
            if isfolder("C:\Program Files (x86)\ILLumiSense")
                [file, path] = uigetfile('*.exe', 'Select an application', "C:\Program Files (x86)\ILLumiSense\ILLumiSense.exe");
            else
                [file, path] = uigetfile('*.exe', 'Select an application');
            end
            
            if file == 0
                return
            else
                app.FBGAppDir = fullfile(path, file);
            end
        end

        % Button pushed function: SaveConfigButton
        function SaveConfigButtonPushed(app, event)
            [file, path] = uiputfile('*.mat', 'Save configuration file', 'NewConfig.mat');
            figure(app.SettingUIFigure)
            if file == 0
                return
            else
                filename = fullfile(path, file);
                app.SaveConfigFileName = file;
            end
            
            config = app.GetherProperties();
            app.Config = config;
            save(filename, '-struct', 'config');
        end

        % Button pushed function: LoadConfigButton
        function LoadConfigButtonPushed(app, event)
            [file, path] = uigetfile('*.mat', 'Select a configuration file');
            figure(app.SettingUIFigure)
            if file == 0
                return
            else
                filename = fullfile(path, file);
                config = load(filename);
            end
            
            app.DistributeProperties(config);
        end

        % Button pushed function: SaveSetdefaultButton
        function SaveSetdefaultButtonPushed(app, event)
            SaveConfigButtonPushed(app);
            ConfigFileName = app.SaveConfigFileName;
            save('init.mat', 'ConfigFileName');
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            app.ApplySettings();
        end

        % Value changed function: ValveControllerProtocolDropDown
        function ValveControllerProtocolDropDownValueChanged(app, event)
            value = app.ValveControllerProtocolDropDown.Value;
            if value == "Serial"
                app.ValveProtocolUIChange('Serial');
            else
                app.ValveProtocolUIChange('TCP/IP');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SettingUIFigure = uifigure('Visible', 'off');
            app.SettingUIFigure.Name = 'Setting';
            app.OKButton = uibutton(app.SettingUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.SettingUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.TabGroup = uitabgroup(app.SettingUIFigure);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.UpdateRateControlPanel = uipanel(app.GeneralTab);
            app.UpdateRateControlPanel.Title = 'Update rate control';
            app.QueryintervalinsEditFieldLabel = uilabel(app.UpdateRateControlPanel);
            app.QueryintervalinsEditFieldLabel.Text = 'Query interval in (s)';
            app.QueryIntervalEditField = uieditfield(app.UpdateRateControlPanel, 'numeric');
            app.QueryIntervalEditField.Limits = [0 Inf];
            app.QueryIntervalEditField.Value = 0.05;
            app.UIupdateintervalinsEditFieldLabel = uilabel(app.UpdateRateControlPanel);
            app.UIupdateintervalinsEditFieldLabel.Text = 'UI update interval in (s)';
            app.UIUpdateIntervalEditField = uieditfield(app.UpdateRateControlPanel, 'numeric');
            app.UIUpdateIntervalEditField.Limits = [0 Inf];
            app.UIUpdateIntervalEditField.Value = 0.2;
            app.GraphicsControlPanel = uipanel(app.GeneralTab);
            app.GraphicsControlPanel.Title = 'Graphics control';
            app.UIaxislimitinsEditFieldLabel = uilabel(app.GraphicsControlPanel);
            app.UIaxislimitinsEditFieldLabel.Text = 'UI axis limit in (s)';
            app.UIAxisLimitEditField = uieditfield(app.GraphicsControlPanel, 'numeric');
            app.UIAxisLimitEditField.Limits = [0 Inf];
            app.UIAxisLimitEditField.Value = 20;
            app.LinewidthEditFieldLabel = uilabel(app.GraphicsControlPanel);
            app.LinewidthEditFieldLabel.Text = 'Line width';
            app.PlotLineWidthEditField = uieditfield(app.GraphicsControlPanel, 'numeric');
            app.PlotLineWidthEditField.Limits = [0 Inf];
            app.PlotLineWidthEditField.Value = 1;
            app.ControllersTab = uitab(app.TabGroup);
            app.ControllersTab.Tooltip = {''};
            app.ControllersTab.Title = 'Controllers';
            app.CommRobotPanel = uipanel(app.ControllersTab);
            app.CommRobotPanel.Title = 'Communication - Robot Controller';
            app.PortLabel = uilabel(app.CommRobotPanel);
            app.PortLabel.Text = 'Port';
            app.RobotControllerCOMDropDown = uidropdown(app.CommRobotPanel);
            app.RobotControllerCOMDropDown.Items = {'COM1', ''};
            app.RobotControllerCOMDropDown.Value = 'COM1';
            app.ProtocolDropDownLabel = uilabel(app.CommRobotPanel);
            app.ProtocolDropDownLabel.Text = 'Protocol';
            app.RobotControllerProtocolDropDown = uidropdown(app.CommRobotPanel);
            app.RobotControllerProtocolDropDown.Items = {'Serial', 'TCP/IP'};
            app.RobotControllerProtocolDropDown.ValueChangedFcn = createCallbackFcn(app, @RobotControllerProtocolDropDownValueChanged, true);
            app.RobotControllerProtocolDropDown.Value = 'Serial';
            app.RemoteAddressEditFieldLabel = uilabel(app.CommRobotPanel);
            app.RemoteAddressEditFieldLabel.Enable = 'off';
            app.RemoteAddressEditFieldLabel.Text = 'Remote Address';
            app.RobotControllerRemoteAddressEditField = uieditfield(app.CommRobotPanel, 'text');
            app.RobotControllerRemoteAddressEditField.Enable = 'off';
            app.RobotControllerRemoteAddressEditField.Value = '192.168.0.7';
            app.BaudRateEditField_2Label = uilabel(app.CommRobotPanel);
            app.BaudRateEditField_2Label.Text = 'Baud Rate';
            app.RobotControllerBaudRateEditField = uieditfield(app.CommRobotPanel, 'numeric');
            app.RobotControllerBaudRateEditField.Limits = [0 Inf];
            app.RobotControllerBaudRateEditField.RoundFractionalValues = 'on';
            app.RobotControllerBaudRateEditField.Value = 5000000;
            app.PortEditField_2Label = uilabel(app.CommRobotPanel);
            app.PortEditField_2Label.Enable = 'off';
            app.PortEditField_2Label.Text = 'Port';
            app.RobotControllerPortEditField = uieditfield(app.CommRobotPanel, 'numeric');
            app.RobotControllerPortEditField.Limits = [0 Inf];
            app.RobotControllerPortEditField.Enable = 'off';
            app.RobotControllerPortEditField.Value = 23;
            app.ParametersPanel = uipanel(app.ControllersTab);
            app.ParametersPanel.Title = 'Parameters';
            app.StepsizeinsLabel = uilabel(app.ParametersPanel);
            app.StepsizeinsLabel.Text = 'Step size in (s)';
            app.ControllerStepSizeEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.ControllerStepSizeEditField.Limits = [0 Inf];
            app.ControllerStepSizeEditField.Value = 0.025;
            app.FrameSizeEditFieldLabel = uilabel(app.ParametersPanel);
            app.FrameSizeEditFieldLabel.Text = 'Frame Size';
            app.ControllerFrameSizeEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.ControllerFrameSizeEditField.Limits = [0 Inf];
            app.ControllerFrameSizeEditField.RoundFractionalValues = 'on';
            app.ControllerFrameSizeEditField.Value = 12;
            app.CommValvePanel = uipanel(app.ControllersTab);
            app.CommValvePanel.Title = 'Communication - Valve Controller';
            app.PortLabel_2 = uilabel(app.CommValvePanel);
            app.PortLabel_2.Text = 'Port';
            app.ValveControllerCOMDropDown = uidropdown(app.CommValvePanel);
            app.ValveControllerCOMDropDown.Items = {'COM1', ''};
            app.ValveControllerCOMDropDown.Value = 'COM1';
            app.ProtocolDropDownLabel_2 = uilabel(app.CommValvePanel);
            app.ProtocolDropDownLabel_2.Text = 'Protocol';
            app.ValveControllerProtocolDropDown = uidropdown(app.CommValvePanel);
            app.ValveControllerProtocolDropDown.Items = {'Serial', 'TCP/IP'};
            app.ValveControllerProtocolDropDown.ValueChangedFcn = createCallbackFcn(app, @ValveControllerProtocolDropDownValueChanged, true);
            app.ValveControllerProtocolDropDown.Value = 'Serial';
            app.RemoteAddressEditFieldLabel_2 = uilabel(app.CommValvePanel);
            app.RemoteAddressEditFieldLabel_2.Enable = 'off';
            app.RemoteAddressEditFieldLabel_2.Text = 'Remote Address';
            app.ValveControllerRemoteAddressEditField = uieditfield(app.CommValvePanel, 'text');
            app.ValveControllerRemoteAddressEditField.Enable = 'off';
            app.ValveControllerRemoteAddressEditField.Value = '192.168.0.8';
            app.BaudRateEditField_2Label_2 = uilabel(app.CommValvePanel);
            app.BaudRateEditField_2Label_2.Text = 'Baud Rate';
            app.ValveControllerBaudRateEditField = uieditfield(app.CommValvePanel, 'numeric');
            app.ValveControllerBaudRateEditField.Limits = [0 Inf];
            app.ValveControllerBaudRateEditField.RoundFractionalValues = 'on';
            app.ValveControllerBaudRateEditField.Value = 5000000;
            app.PortEditField_2Label_2 = uilabel(app.CommValvePanel);
            app.PortEditField_2Label_2.Enable = 'off';
            app.PortEditField_2Label_2.Text = 'Port';
            app.ValveControllerPortEditField = uieditfield(app.CommValvePanel, 'numeric');
            app.ValveControllerPortEditField.Limits = [0 Inf];
            app.ValveControllerPortEditField.Enable = 'off';
            app.ValveControllerPortEditField.Value = 23;
            app.FBGTab = uitab(app.TabGroup);
            app.FBGTab.Title = 'FBG';
            app.FBGTCPIPPanel = uipanel(app.FBGTab);
            app.FBGTCPIPPanel.Title = 'Communication';
            app.RemoteAddressEditField_2Label = uilabel(app.FBGTCPIPPanel);
            app.RemoteAddressEditField_2Label.Text = 'Remote Address';
            app.FBGRemoteAddressEditField = uieditfield(app.FBGTCPIPPanel, 'text');
            app.FBGRemoteAddressEditField.Value = '127.0.0.1';
            app.ControlportEditFieldLabel = uilabel(app.FBGTCPIPPanel);
            app.ControlportEditFieldLabel.Text = 'Control port';
            app.FBGControlPortEditField = uieditfield(app.FBGTCPIPPanel, 'numeric');
            app.FBGControlPortEditField.Limits = [0 Inf];
            app.FBGControlPortEditField.RoundFractionalValues = 'on';
            app.FBGControlPortEditField.Value = 8002;
            app.DatastreamingportLabel_2 = uilabel(app.FBGTCPIPPanel);
            app.DatastreamingportLabel_2.Text = 'Data streaming port';
            app.FBGDataStreamPortEditField = uieditfield(app.FBGTCPIPPanel, 'numeric');
            app.FBGDataStreamPortEditField.Limits = [0 Inf];
            app.FBGDataStreamPortEditField.RoundFractionalValues = 'on';
            app.FBGDataStreamPortEditField.Value = 2055;
            app.ScanrateinHzEditFieldLabel = uilabel(app.FBGTab);
            app.ScanrateinHzEditFieldLabel.Text = 'Scan rate in Hz';
            app.FBGScanRateEditField = uieditfield(app.FBGTab, 'numeric');
            app.FBGScanRateEditField.Limits = [0 Inf];
            app.FBGScanRateEditField.Value = 40;
            app.FBGAppPanel = uipanel(app.FBGTab);
            app.FBGAppPanel.Title = 'Interrogator application';
            app.FBGAppDirBrowseButton = uibutton(app.FBGAppPanel, 'push');
            app.FBGAppDirBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @FBGAppDirBrowseButtonPushed, true);
            app.FBGAppDirBrowseButton.Text = 'Browse';
            app.InterrogatorapplicationdirectoryEditFieldLabel = uilabel(app.FBGAppPanel);
            app.InterrogatorapplicationdirectoryEditFieldLabel.Text = 'Interrogator application directory';
            app.FBGAppDirEditField = uieditfield(app.FBGAppPanel, 'text');
            app.FBGAppDirEditField.Value = 'C:\Program Files (x86)\ILLumiSense\ILLumiSense.exe';
            app.LaunchFBGAppAtStartupCheckBox = uicheckbox(app.FBGAppPanel);
            app.LaunchFBGAppAtStartupCheckBox.Text = 'Launch FBG interrogator application at startup';
            app.ApplyButton = uibutton(app.SettingUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.LoadConfigButton = uibutton(app.SettingUIFigure, 'push');
            app.LoadConfigButton.ButtonPushedFcn = createCallbackFcn(app, @LoadConfigButtonPushed, true);
            app.LoadConfigButton.Text = 'Load';
            app.SaveConfigButton = uibutton(app.SettingUIFigure, 'push');
            app.SaveConfigButton.ButtonPushedFcn = createCallbackFcn(app, @SaveConfigButtonPushed, true);
            app.SaveConfigButton.Text = 'Save';
            app.SaveSetdefaultButton = uibutton(app.SettingUIFigure, 'push');
            app.SaveSetdefaultButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSetdefaultButtonPushed, true);
            app.SaveSetdefaultButton.Text = 'Save & Set default';
            % Show the figure after all components are created
            app.SettingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = setting(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SettingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SettingUIFigure)
        end
    end
end